classdef Jetson_Live_Updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Detected_Pose                   matlab.ui.container.Panel
        roll                            matlab.ui.control.EditField
        yLabel_5                        matlab.ui.control.Label
        pitch                           matlab.ui.control.EditField
        yLabel_4                        matlab.ui.control.Label
        yaw                             matlab.ui.control.EditField
        yLabel_3                        matlab.ui.control.Label
        EuleranglesXYZdegreesLabel      matlab.ui.control.Label
        ozValue                         matlab.ui.control.EditField
        yLabel_2                        matlab.ui.control.Label
        OrientationquaternionwxyzLabel  matlab.ui.control.Label
        PositionmetersLabel             matlab.ui.control.Label
        oyValue                         matlab.ui.control.EditField
        yLabel                          matlab.ui.control.Label
        oxValue                         matlab.ui.control.EditField
        xLabel                          matlab.ui.control.Label
        owValue                         matlab.ui.control.EditField
        wLabel                          matlab.ui.control.Label
        pzValue                         matlab.ui.control.EditField
        ZLabel                          matlab.ui.control.Label
        pyValue                         matlab.ui.control.EditField
        gyLabel                         matlab.ui.control.Label
        pxValue                         matlab.ui.control.EditField
        XLabel                          matlab.ui.control.Label
        FPSEditFieldLabel               matlab.ui.control.Label
        ConnectionStatusLabel           matlab.ui.control.Label
        ConnectionStatusTextArea        matlab.ui.control.TextArea
        FPS                             matlab.ui.control.EditField
        LivePreviewLamp                 matlab.ui.control.Lamp
        LivePreviewLampLabel            matlab.ui.control.Label
        JETSONCAMERALIVESTREAMAPPLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        SHUTDOWNXButton                 matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hw              % Jetson hardware connection
        cam             % Camera object
        camName    %Camera name
        camRes        %Camera resolution
        timerObj        % Timer for frame updates
        isInitialized   % Logical flag to track initialization
        
        % ROS2 Objects
        rosNode = []
        imageSubscriber = []
        statusSubscriber = []
        commandPublisher = []
        poseSubscriber = []   % <<<< NEW: Subscriber for EKF pose data
   
        % Pose estimation data
        latestPose = []       % <<<< NEW: Latest pose from visual estimator
        
        % Camera state
        isConnected = false
        isStreaming = false

        % Timer for updates
        updateTimer = []

        % Performance tracking
        frameCount = 0
        lastFPSTime = 0
        currentFPS = 0

        % Image data
        latestImage = []

        % Graphics handles for 3D visualization
        poseLine    % handle to trajectory plot (Line)
        poseFrame   % handle to pose frame (poseplot object or hgtransform)

        % 3D Visualization properties
        posePlotTimer = []
        isPosePlotActive = false
        poseHistory = []      % Store pose history for trajectory
        maxHistoryPoints = 100 % How many poses to keep in history
        poseAxesHandle = []   % Handle to the axes
        poseFrameHandle = []  % Handle to coordinate frame
        poseTrajectoryHandle = [] % Handle to trajectory line
        referenceFrameHandle = [] % Handle to world reference frame

    end
    
    methods (Access = private)
        
        % --- Function to initialize the Jetson connection ---
        function initializeJetson(app)
            try
                % Change lamp to amber (connecting)
                app.LivePreviewLamp.Color = [1 0.4118 0.1608]; % orange
                pause(1);

                app.isInitialized = true;

                % Lamp stays amber after successful init
                app.LivePreviewLamp.Color = [1 0.4118 0.1608];
                disp('Jetson initialized successfully.');

            catch ME
                app.LivePreviewLamp.Color = [1 0 0]; % red
                uialert(app.UIFigure, ...
                    sprintf('Initialization failed:\n%s', ME.message), ...
                    'Initialization Error');
                app.isInitialized = false;
            end
        end

% NEW connectToROS2 function (with pose subscriber added):
function connectToROS2(app)
    try
        % Set ROS2 domain ID (must match Jetson)
        setenv('ROS_DOMAIN_ID', '1');

        % Create ROS2 node
        app.rosNode = ros2node("/matlab_jetson_app");
        
        % Create subscriber for status messages
        app.statusSubscriber = ros2subscriber(...
            app.rosNode, ...
            "/jetson/ekf/status", ...
            "std_msgs/String", ...
            @app.statusCallback, ...
            "Reliability","besteffort","Durability","volatile");

        % Create publisher for sending commands
        app.commandPublisher = ros2publisher(...
            app.rosNode, ...
            "/jetson/ekf/command", ...
            "std_msgs/String");
        
        % <<<< NEW: Create subscriber for pose data from visual estimator
        app.poseSubscriber = ros2subscriber(...
            app.rosNode, ...
            "/pose_ekf", ...
            "geometry_msgs/Pose", ...
            @app.poseCallback, ...
            "Reliability","besteffort","Durability","volatile");
        
        app.isConnected = true;
        app.LivePreviewLamp.Color = [1 0.4118 0.1608]; % Amber/Orange
        app.ConnectionStatusTextArea.Value = "Connected to ROS2. Jetson initialized.";

        % Log available topics
        app.logAvailableTopics();
    catch ME
        % ... error handling
        app.ConnectionStatusTextArea.Value = sprintf("ROS2 Connection Error: %s", ME.message);
        app.isConnected = false;
        app.LivePreviewLamp.Color = [1 0 0]; % Red
        rethrow(ME);
    end
end


        % Callback for status messages
        function statusCallback(app, msg)
            statusMsg = char(msg.data);
            app.ConnectionStatusTextArea.Value = sprintf("[Jetson] %s", statusMsg);
        end

        % Send command to Jetson
        function sendCommand(app, command)
      
            if app.isConnected && ~isempty(app.commandPublisher)
                msg = ros2message(app.commandPublisher);
                msg.data = command;
                send(app.commandPublisher, msg);
            end
        end

   
        % Callback for pose data from visual estimator
        function poseCallback(app, msg)
            try
                % Extract position
                px = msg.position.x;
                py = msg.position.y;
                pz = msg.position.z;

                % Extract orientation (quaternion wxyz)
                qw = msg.orientation.w;
                qx = msg.orientation.x;
                qy = msg.orientation.y;
                qz = msg.orientation.z;

                % Store latest pose
                app.latestPose = [px; py; pz; qw; qx; qy; qz];

                % Only update UI if we have a valid pose
                if ~any(isnan(app.latestPose))
                    % --- Update numeric fields ---
                    app.pxValue.Value = sprintf('%.3f', px);
                    app.pyValue.Value = sprintf('%.3f', py);
                    app.pzValue.Value = sprintf('%.3f', pz);
                    app.owValue.Value = sprintf('%.3f', qw);
                    app.oxValue.Value = sprintf('%.3f', qx);
                    app.oyValue.Value = sprintf('%.3f', qy);
                    app.ozValue.Value = sprintf('%.3f', qz);

                    % --- Update Euler angles ---
                    try
                        %R = quat2rotm([qw, qx, qy, qz]);
                        eul_rad = quat2eul([qw, qx, qy, qz], 'XYZ');
                        eul_deg = rad2deg(eul_rad);
                        app.yaw.Value   = sprintf('%.1f°', eul_deg(1));
                        app.pitch.Value = sprintf('%.1f°', eul_deg(2));
                        app.roll.Value  = sprintf('%.1f°', eul_deg(3));
                    catch
                        app.yaw.Value = 'N/A';
                        app.pitch.Value = 'N/A';
                        app.roll.Value = 'N/A';
                    end

                    % Update 3D visualization
                    app.updatePosePlot();

                    % --- Update 3D visualization ---
                    %persistent trajLine poseFrame
                    if isempty(app.poseLine) || ~isvalid(app.poseLine)
                        % Initialize 3D plot
                        hold(app.UIAxes, 'on');
                        grid(app.UIAxes, 'on');
                        view(app.UIAxes, 3);
                        axis(app.UIAxes, 'equal');
                        xlabel(app.UIAxes, 'X (m)');
                        ylabel(app.UIAxes, 'Y (m)');
                        zlabel(app.UIAxes, 'Z (m)');
                        title(app.UIAxes, '3D Pose Visualization');

                        % Create trajectory line (path)
                         app.poseLine = plot3(app.UIAxes, px, py, pz, 'b-', 'LineWidth', 1.5);

                        % Create current orientation frame
                        T = [quat2rotm([qw qx qy qz]), [px py pz]'];
                        T = [T; 0 0 0 1];
                        app.poseFrame = poseplot(app.UIAxes, T, 'FrameSize', 0.05, 'Tag', 'Pose');
                    else
                        % Update trajectory
                        app.poseLine.XData(end+1) = px;
                        app.poseLine.YData(end+1) = py;
                        app.poseLine.ZData(end+1) = pz;

                        % Update orientation frame
                        %T = trvec2tform([px py pz]) * quat2tform([qw qx qy qz]);
                        T = [quat2rotm([qw qx qy qz]), [px py pz]'];
                        T = [T; 0 0 0 1];
                        set(app.poseFrame, 'Pose', T);
                    end
               
                end

                drawnow limitrate nocallbacks;

            catch ME
                app.ConnectionStatusTextArea.Value = sprintf("Pose callback error: %s", ME.message);
            end
    end


        % Start update timer for UI refresh
        function startUpdateTimer(app)
            % If timer exists and is running, stop it first
            if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
                stop(app.updateTimer);
                delete(app.updateTimer);
            end

            % Create a fresh timer
            app.updateTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.05, ... % 20Hz for responsive UI
                'TimerFcn', @(~,~) app.updateDisplay(), ...
                'BusyMode','drop'); % Skip if still processing
                      
            % Reset performance counters
            app.frameCount = 0;
            app.lastFPSTime = tic;
            start(app.updateTimer);
        end

        % Update display with latest image
        function updateDisplay(app)
                if ~isempty(app.latestImage) && app.isStreaming
                    % Update the image display
                    app.Image.ImageSource = app.latestImage;
                    drawnow limitrate nocallbacks;
                end
        end
        
        % Log available ROS2 topics
        function logAvailableTopics(app)
            try
                [topics, types] = ros2('topic', 'list');
                app.ConnectionStatusTextArea.Value = [app.ConnectionStatusTextArea.Value; 
                    sprintf("Found %d ROS2 topics:", numel(topics))];
                
                for i = 1:min(numel(topics), 10) % Show first 10 topics
                    app.ConnectionStatusTextArea.Value = [app.ConnectionStatusTextArea.Value;
                        sprintf("  %s [%s]", topics{i}, types{i})];
                end
            catch
                % Silently fail if topic listing doesn't work
            end
        end
        
 function clearPoseVisualization(app)
    % Delete trajectory line
    try
        if ~isempty(app.poseLine) && isvalid(app.poseLine)
            delete(app.poseLine);
        end
    catch
    end
    app.poseLine = [];

    % Delete pose frame
    try
        if ~isempty(app.poseFrame) && isvalid(app.poseFrame)
            delete(app.poseFrame);
        end
    catch
    end
    app.poseFrame = [];

    % Clear axes
    cla(app.UIAxes);
    grid(app.UIAxes,'on');
    xlabel(app.UIAxes, 'X (m)');
    ylabel(app.UIAxes, 'Y (m)');
    zlabel(app.UIAxes, 'Z (m)');
    title(app.UIAxes, '3D Pose Visualization');
    drawnow;
end


        % NEW closeApp function (with pose subscriber cleanup):
        function closeApp(app, ~)
            try
                if app.isStreaming
                    app.STOPButtonPushed([], []);
                end
                
                if app.isConnected
                    clear app.commandPublisher app.imageSubscriber ...
                          app.statusSubscriber app.poseSubscriber app.rosNode;  % <<<< Added poseSubscriber
                    app.isConnected = false;
                end
                
                % Clean up timer
                        if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
                            delete(app.updateTimer);
                        end
            catch
                % ...
            end
            delete(app.UIFigure);
        end
        
        
        function initializePosePlot(app)
            % Initialize 3D pose visualization
        
            % Clear existing plot
            cla(app.UIAxes2);
        
            % Set up the axes
            app.UIAxes2.Title.String = 'Pose Visualization';
            app.UIAxes2.XLabel.String = 'X (meters)';
            app.UIAxes2.YLabel.String = 'Y (meters)';
            app.UIAxes2.ZLabel.String = 'Z (meters)';
            app.UIAxes2.View = [45, 30]; % Set view angle
            grid(app.UIAxes2, 'on');
            hold(app.UIAxes2, 'on');
            axis(app.UIAxes2, 'equal');
        
            % Set reasonable limits (adjust based on your expected workspace)
            xlim(app.UIAxes2, [-2, 2]);
            ylim(app.UIAxes2, [-2, 2]);
            zlim(app.UIAxes2, [0, 4]);
        
            % Draw world reference frame (X=red, Y=green, Z=blue)
            origin = [0, 0, 0];
            scale = 0.5;
        
            % X-axis (red)
            x_line = quiver3(app.UIAxes2, origin(1), origin(2), origin(3), ...
                         scale, 0, 0, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
            % Y-axis (green)
            y_line = quiver3(app.UIAxes2, origin(1), origin(2), origin(3), ...
                         0, scale, 0, 'g', 'LineWidth', 2, 'MaxHeadSize', 0.5);
            % Z-axis (blue)
            z_line = quiver3(app.UIAxes2, origin(1), origin(2), origin(3), ...
                         0, 0, scale, 'b', 'LineWidth', 2, 'MaxHeadSize', 0.5);
        
            app.referenceFrameHandle = [x_line; y_line; z_line];
        
            % Initialize empty trajectory line
            app.poseTrajectoryHandle = plot3(app.UIAxes2, [], [], [], ...
                                        'k-', 'LineWidth', 1, 'MarkerSize', 8);
        
            % Initialize empty coordinate frame for camera
            app.poseFrameHandle = [];
        
            hold(app.UIAxes2, 'off');
            drawnow;
        
            app.isPosePlotActive = true;        
        end

        
        function  updatePosePlot(app)
            % Update the 3D plot with latest pose
        
            if ~app.isPosePlotActive || isempty(app.latestPose)
                return;
            end
        
            % Extract pose
            px = app.latestPose(1);
            py = app.latestPose(2);
            pz = app.latestPose(3);
            qw = app.latestPose(4);
            qx = app.latestPose(5);
            qy = app.latestPose(6);
            qz = app.latestPose(7);
        
            % Skip if pose is invalid
            if any(isnan([px, py, pz, qw, qx, qy, qz]))
                return;
            end
        
            % Convert quaternion to rotation matrix
            quat = [qw, qx, qy, qz];
            R = quat2rotm(quat);

            % Store in history
            if isempty(app.poseHistory)
                app.poseHistory = [px, py, pz];
            else
                app.poseHistory = [app.poseHistory; [px, py, pz]];
                % Keep only recent history
                if size(app.poseHistory, 1) > app.maxHistoryPoints
                    app.poseHistory = app.poseHistory(end-app.maxHistoryPoints+1:end, :);
                end
            end
        
            % Update plot
            hold(app.UIAxes2, 'on');

            % Remove old camera frame if it exists
            if ~isempty(app.poseFrameHandle) && isvalid(app.poseFrameHandle(1))
                delete(app.poseFrameHandle);
            end

            % Draw new camera coordinate frame
            frame_scale = 0.2; % Smaller scale for camera frame
            origin = [px, py, pz];

            % Camera X-axis (red) - camera's right
            x_end = origin + (R(:,1)' * frame_scale);
            x_line = plot3(app.UIAxes2, [origin(1), x_end(1)], ...
                [origin(2), x_end(2)], ...
                [origin(3), x_end(3)], ...
                'r-', 'LineWidth', 3);

            % Camera Y-axis (green) - camera's down
            y_end = origin + (R(:,2)' * frame_scale);
            y_line = plot3(app.UIAxes2, [origin(1), y_end(1)], ...
                [origin(2), y_end(2)], ...
                [origin(3), y_end(3)], ...
                'g-', 'LineWidth', 3);

            % Camera Z-axis (blue) - camera's forward
            z_end = origin + (R(:,3)' * frame_scale);
            z_line = plot3(app.UIAxes2, [origin(1), z_end(1)], ...
                [origin(2), z_end(2)], ...
                [origin(3), z_end(3)], ...
                'b-', 'LineWidth', 3);

            % Draw camera as a small pyramid/cube
            camera_size = 0.1;
            vertices = [
                0, 0, 0;
                camera_size, camera_size, camera_size;
                camera_size, -camera_size, camera_size;
                -camera_size, -camera_size, camera_size;
                -camera_size, camera_size, camera_size;
                ];

            % Transform vertices to camera pose
            vertices_world = (R * vertices')' + origin;

            % Draw camera body (magenta)
            camera_handle = plot3(app.UIAxes2, ...
                vertices_world(:,1), vertices_world(:,2), vertices_world(:,3), ...
                'm-', 'LineWidth', 2, 'Marker', 'o', 'MarkerSize', 6);

            app.poseFrameHandle = [x_line; y_line; z_line; camera_handle];

            % Update trajectory line
            if size(app.poseHistory, 1) > 1
                set(app.poseTrajectoryHandle, ...
                    'XData', app.poseHistory(:,1), ...
                    'YData', app.poseHistory(:,2), ...
                    'ZData', app.poseHistory(:,3));
            end

            hold(app.UIAxes2, 'off');
            drawnow limitrate;
        end
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            try
                % Stop the stream on Jetson
                if app.isConnected
                    app.sendCommand('stop');
                    app.ConnectionStatusTextArea.Value = "Stream stopped. " + ...
                        "Sending stop command to Jetson...";

                end

                % Stop update timer
                if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
                    stop(app.updateTimer);
                    delete(app.updateTimer);
                    app.updateTimer = [];
                end

                if ~isempty(app.cam)
                    clear app.cam
                    app.cam = [];
                end
                
                app.STARTButton.Enable = 'on';
                app.STOPButton.Enable = 'on';
                app.SHUTDOWNXButton.Enable = "on";

                app.LivePreviewLamp.Color = [1 0.4118 0.1608]; % Amber/Orange
                %app.Image.ImageSource = []; % Clear displayed image
                blankFrame = uint8(zeros(480, 640, 3)); % adjust to your stream resolution
                app.Image.ImageSource = blankFrame;
                drawnow;

                app.ConnectionStatusTextArea.Value = "Streaming stopped. Ready for new connection.";
                if app.isStreaming
                    disp('Camera stopped successfully.');
                    app.isStreaming = false;
                end
               
                % Clear 3D plot
                if ~isempty(app.poseFrameHandle)
                    delete(app.poseFrameHandle);
                    app.poseFrameHandle = [];
                end

                % Clear history
                app.poseHistory = [];
                app.isPosePlotActive = false;

            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Error stopping camera:\n%s', ME.message), ...
                    'Stop Error');
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
             try
                 % Check if already connected to ROS2
                if ~app.isConnected
                    % Connect to ROS2 (domain 0)
                    app.connectToROS2();

                    if ~app.isConnected
                        error('Failed to connect to ROS2 network');
                    end
                end

                % Send start command to Jetson
                app.sendCommand('start');
                app.ConnectionStatusTextArea.Value = "Sending start command to Jetson...";

                % Start the update timer
                app.startUpdateTimer();

                % Initialize 3D pose plot
                app.initializePosePlot();

                % Update UI state
                app.isStreaming = true;
                app.LivePreviewLamp.Color = [0 1 0]; % Green
                app.STARTButton.Enable = 'on';
                app.STOPButton.Enable = 'on';
                
                app.ConnectionStatusTextArea.Value = "Stream started. Waiting for images...";

                % Change lamp to green
                app.LivePreviewLamp.Color = [0 1 0];

            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Failed to start camera:\n%s', ME.message), ...
                    'Camera Error');
                app.LivePreviewLamp.Color = [1 0 0]; % Red
                app.isStreaming = false;
               
            end
        end

        % Callback function
        function INITIALIZEButtonPushed(app, event)
         
        end

        % Button pushed function: SHUTDOWNXButton
        function SHUTDOWNXButtonPushed(app, event)
            if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
                stop(app.updateTimer);
                delete(app.updateTimer);
                app.updateTimer = [];
                % Clear plots
                clearPoseVisualization(app);
            end

            app.sendCommand('shutdown');
            app.ConnectionStatusTextArea.Value = "Shutting down..";
            %app.SHUTDOWNXButton.Enable = "off";
            %app.STARTButton.Enable = "on";
            %app.STOPButton.Enable ="off";
            app.LivePreviewLamp.Color = [1 0 0]; % Red
            app.isInitialized = false;
            %app.Image.ImageSource = blankFrame;
            app.Image.ImageSource = fullfile( 'Nvidia-logo.png');
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'IMU Reading plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Camera Pose Visualization')
            xlabel(app.UIAxes2, 'X (meters)')
            ylabel(app.UIAxes2, 'Y (meters)')
            zlabel(app.UIAxes2, 'Z (meters)')
            app.Panel = uipanel(app.UIFigure);
            app.STARTButton = uibutton(app.Panel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.Panel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.SHUTDOWNXButton = uibutton(app.Panel, 'push');
            app.SHUTDOWNXButton.ButtonPushedFcn = createCallbackFcn(app, @SHUTDOWNXButtonPushed, true);
            app.SHUTDOWNXButton.Text = 'SHUTDOWN X';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Nvidia-logo.png');
            app.JETSONCAMERALIVESTREAMAPPLabel = uilabel(app.UIFigure);
            app.JETSONCAMERALIVESTREAMAPPLabel.Text = 'JETSON  CAMERA LIVE STREAM APP';
            app.LivePreviewLampLabel = uilabel(app.UIFigure);
            app.LivePreviewLampLabel.Text = 'Live Preview';
            app.LivePreviewLamp = uilamp(app.UIFigure);
            app.FPS = uieditfield(app.UIFigure, 'text');
            app.FPS.Value = '0.0';
            app.ConnectionStatusTextArea = uitextarea(app.UIFigure);
            app.ConnectionStatusTextArea.Value = {'Initializing app...'; 'Press START to begin.'};
            app.ConnectionStatusLabel = uilabel(app.UIFigure);
            app.ConnectionStatusLabel.Text = 'ConnectionStatus:';
            app.FPSEditFieldLabel = uilabel(app.UIFigure);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.Detected_Pose = uipanel(app.UIFigure);
            app.XLabel = uilabel(app.Detected_Pose);
            app.XLabel.Text = 'X:';
            app.pxValue = uieditfield(app.Detected_Pose, 'text');
            app.gyLabel = uilabel(app.Detected_Pose);
            app.gyLabel.Text = 'Y:';
            app.pyValue = uieditfield(app.Detected_Pose, 'text');
            app.ZLabel = uilabel(app.Detected_Pose);
            app.ZLabel.Text = 'Z:';
            app.pzValue = uieditfield(app.Detected_Pose, 'text');
            app.wLabel = uilabel(app.Detected_Pose);
            app.wLabel.Text = 'w:';
            app.owValue = uieditfield(app.Detected_Pose, 'text');
            app.xLabel = uilabel(app.Detected_Pose);
            app.xLabel.Text = 'x:';
            app.oxValue = uieditfield(app.Detected_Pose, 'text');
            app.yLabel = uilabel(app.Detected_Pose);
            app.yLabel.Text = 'y:';
            app.oyValue = uieditfield(app.Detected_Pose, 'text');
            app.PositionmetersLabel = uilabel(app.Detected_Pose);
            app.PositionmetersLabel.Text = 'Position (meters)';
            app.OrientationquaternionwxyzLabel = uilabel(app.Detected_Pose);
            app.OrientationquaternionwxyzLabel.Text = 'Orientation(quaternion wxyz)';
            app.yLabel_2 = uilabel(app.Detected_Pose);
            app.yLabel_2.Text = 'z:';
            app.ozValue = uieditfield(app.Detected_Pose, 'text');
            app.EuleranglesXYZdegreesLabel = uilabel(app.Detected_Pose);
            app.EuleranglesXYZdegreesLabel.Text = 'Euler angles (XYZ, degrees)';
            app.yLabel_3 = uilabel(app.Detected_Pose);
            app.yLabel_3.Text = 'Yaw:';
            app.yaw = uieditfield(app.Detected_Pose, 'text');
            app.yLabel_4 = uilabel(app.Detected_Pose);
            app.yLabel_4.Text = 'Pitch:';
            app.pitch = uieditfield(app.Detected_Pose, 'text');
            app.yLabel_5 = uilabel(app.Detected_Pose);
            app.yLabel_5.Text = 'Roll:';
            app.roll = uieditfield(app.Detected_Pose, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Jetson_Live_Updated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end